classdef loqu_book < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        D__Label           matlab.ui.control.Label
        Image              matlab.ui.control.Image
        GridLayout11       matlab.ui.container.GridLayout
        Lamp               matlab.ui.control.Lamp
        Gauge              matlab.ui.control.LinearGauge
        GridLayout10       matlab.ui.container.GridLayout
        HOMEButton         matlab.ui.control.StateButton
        GridLayout9        matlab.ui.container.GridLayout
        RECORDButton       matlab.ui.control.StateButton
        GridLayout7        matlab.ui.container.GridLayout
        PLAYButton         matlab.ui.control.StateButton
        GridLayout6        matlab.ui.container.GridLayout
        SETTINGSButton     matlab.ui.control.StateButton
        GridLayout3        matlab.ui.container.GridLayout
        NEXTButton         matlab.ui.control.StateButton
        CHECKButton        matlab.ui.control.StateButton
        GridLayout2        matlab.ui.container.GridLayout
        SOUNDButton        matlab.ui.control.StateButton
        STSYLLABLEButton   matlab.ui.control.StateButton
        SENTANCEButton     matlab.ui.control.StateButton
        SPELLINGButton     matlab.ui.control.StateButton
        FIRSTLETTERButton  matlab.ui.control.StateButton
        DESCRIPTIONButton  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        recObj = audiorecorder % rec
        recDuration = 3; % dur
        y = 0;
        transcriber = speechClient("wav2vec2.0")
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RECORDButton
        function RECORDButtonPushed(app, event)
            app.Lamp.Color = 'g';
            recordblocking(app.recObj,app.recDuration);
            app.y = getaudiodata(app.recObj);
            app.Lamp.Color = 'r';

            %value = app.RECORDButton.Value;
            
        end

        % Value changed function: PLAYButton
        function PLAYButtonPushed(app, event)
            player = audioplayer(app.y, app.recObj.SampleRate);
            playblocking(player);
            %value = app.RECORDButton.Value;
            
        end

        % Value changed function: FIRSTLETTERButton
        function LETTERButtonPushed(app, event)
            if app.FIRSTLETTERButton.Value
                app.D__Label.Visible = true;
            else
                app.D__Label.Visible = false;
            end
            %value = app.FIRSTLETTERButton.Value;
            
        end

        % Value changed function: NEXTButton
        function NEXTButtonPushed(app, event)
            loqu
            app.UIFigure.Visible = 'off';
            %value = app.NEXTButton.Value;
            
        end

        % Value changed function: CHECKButton
        function CHECKButtonPushed(app, event)
            transcript = speech2text(app.transcriber,app.y,app.recObj.SampleRate)   
            words = transcript{:,1}
            res = loqu_check(words,"dog")
            if res == true
                fig = uifigure;
                uialert(fig,"Please clikc the NEXT button.","Correct!");
            end
            if res == false
                fig = uifigure;
                uialert(fig,"Please try again.","Incorrect Word/Sound");
            end
            % fig = uifigure;
            % uialert(fig,"Please try again.","Incorrect Word/Sound",'Icon','success')

            %value = app.CHECKButton.Value;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DESCRIPTIONButton = uibutton(app.GridLayout2, 'state');
            app.DESCRIPTIONButton.Text = 'DESCRIPTION';
            app.FIRSTLETTERButton = uibutton(app.GridLayout2, 'state');
            app.FIRSTLETTERButton.ValueChangedFcn = createCallbackFcn(app, @LETTERButtonPushed, true);
            app.FIRSTLETTERButton.Text = 'FIRST LETTER';
            app.FIRSTLETTERButton.Value = true;
            app.SPELLINGButton = uibutton(app.GridLayout2, 'state');
            app.SPELLINGButton.Text = 'SPELLING';
            app.SENTANCEButton = uibutton(app.GridLayout2, 'state');
            app.SENTANCEButton.Text = 'SENTANCE';
            app.STSYLLABLEButton = uibutton(app.GridLayout2, 'state');
            app.STSYLLABLEButton.Text = '1ST SYLLABLE';
            app.SOUNDButton = uibutton(app.GridLayout2, 'state');
            app.SOUNDButton.Text = 'SOUND';
            app.CHECKButton = uibutton(app.GridLayout, 'state');
            app.CHECKButton.ValueChangedFcn = createCallbackFcn(app, @CHECKButtonPushed, true);
            app.CHECKButton.Text = 'CHECK';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.NEXTButton = uibutton(app.GridLayout3, 'state');
            app.NEXTButton.ValueChangedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.SETTINGSButton = uibutton(app.GridLayout6, 'state');
            app.SETTINGSButton.Text = 'SETTINGS';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.PLAYButton = uibutton(app.GridLayout7, 'state');
            app.PLAYButton.ValueChangedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.RECORDButton = uibutton(app.GridLayout9, 'state');
            app.RECORDButton.ValueChangedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.HOMEButton = uibutton(app.GridLayout10, 'state');
            app.HOMEButton.Text = 'HOME';
            app.Gauge = uigauge(app.GridLayout, 'linear');
            app.Gauge.Limits = [1 5];
            app.Gauge.MajorTicks = [1 2 3 4 5];
            app.Gauge.MinorTicks = [1 2 3 4 5];
            app.Gauge.Value = 1;
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.Lamp = uilamp(app.GridLayout11);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'an-old-book-isolated-on-white-background.jpg');
            app.D__Label = uilabel(app.GridLayout4);
            app.D__Label.Visible = 'off';
            app.D__Label.Interpreter = 'latex';
            app.D__Label.Text = 'D _ _ ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loqu_book

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end